classdef CalciumTraces < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        NewnonlinearfitButton     matlab.ui.control.Button
        ShowallButton             matlab.ui.control.Button
        ShowlegendCB              matlab.ui.control.CheckBox
        PlottedROIsTextArea       matlab.ui.control.TextArea
        PlottedROIsTextAreaLabel  matlab.ui.control.Label
        df_Axes                   matlab.ui.control.UIAxes
        TracesAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mainApp     % app    - handle to mainApp
        stack       % array  - imported from mainApp
        stackInfo   % struct - imported from mainApp
        fileName    % string - imported from mainApp
        dataPath    % string - imported from mainApp % Description
        
        interactiveElements % Container for all components that should not be
                            % clicked while something like a new window is going on
        
        activeROIs  % array  - stores the information in app.PlottedROIsTextArea in an easy accesible array
                    %           Is also used to make sure no duplicate or
                    %           invalid entries are in the text area
        ROIsData    % struct with ROI stuff - see mainApp for description
        newCalc     % boolean - Determines if some new calculation has to be saved
    end
    
    methods (Access = private)
        
        function switchInteractivity(app,status)
            for i = 1:length(app.UIFigure.Children)
                % Not all Components (e.g. Axes) have the Enable property
                try
                    app.UIFigure.Children(i).Enable = status;
                catch
                    % Everything is fine
                end
            end     
        end
        
        function plotTraces(app)
            % The default "Interpreter" would interpret the "_" in the
            % filename as subscript 
            title(app.TracesAxes, sprintf("Mean ROI-fluorescence of %s with %g ROIs", ...
                app.fileName, size(app.ROIsData.traces,2)), 'Interpreter', 'none')

            if isnan(app.activeROIs)
                return
            else
                % cla to not plot on top of the old traces, as we use hold
                cla(app.TracesAxes)
                hold(app.TracesAxes,'on')
                for i = 1:length(app.activeROIs)
                    currROI = app.activeROIs(i);
                    % There are 7 default plotting colors Matlab cycles
                    % through - by setting this colorIndex (=SeriesIndex)
                    % manually the plotted trace has the same color as the
                    % fit - without assigning colors manually
                    plot(app.TracesAxes, app.ROIsData.traces(:,currROI),'SeriesIndex',rem(i,8))
                    text(app.TracesAxes, app.stackInfo.nFrames+2, app.ROIsData.traces(end,currROI), ...
                        num2str(currROI))
                end
                xlim(app.TracesAxes, [0,app.stackInfo.nFrames + 10])
                ylim(app.TracesAxes,'padded'); 
                %% If we want to let the y Axis start a 0
                % ylims = ylim(app.TracesAxes);
                % ylim(app.TracesAxes, [0, ylims(2)])
                xlabel(app.TracesAxes,sprintf('Frames, at fps of %.2f Hz', app.stackInfo.fps))
                ylabel(app.TracesAxes,'Fluorescence [a.u.]')
                
                if app.ShowlegendCB.Value
                    % The plotted fits would get their own legend, so the
                    % legend is not allowed to update
                    legend(app.TracesAxes, string(app.activeROIs),'AutoUpdate','off');
                else
                    % Otherwise an empty/arbitrary legend remains
                    legend(app.TracesAxes,'off')
                end
                
                showFit = 1;
                if showFit == 1
                    for i = 1:length(app.activeROIs)
                        plot(app.TracesAxes, app.ROIsData.linFit(:,app.activeROIs(i)), 'SeriesIndex',rem(i,8))
                        plot(app.TracesAxes, app.ROIsData.expFit.yFitted(:,app.activeROIs(i)), 'SeriesIndex',rem(i,8))
                    end
                end
                    
                
                plot_dF(app)
                
                %testplot(app)
            end

        end
        
        
        function plot_dF(app)
            title(app.df_Axes, sprintf("df/F0 with %g active ROIs", length(app.activeROIs)), ...
            'Interpreter', 'none')

            cla(app.df_Axes)
            hold(app.df_Axes,'on')
            
            for i = 1:length(app.activeROIs)
                currROI = app.activeROIs(i);
                % There are 7 default plotting colors Matlab cycles
                % through - by setting this colorIndex (=SeriesIndex)
                % manually the plotted trace has the same color as the
                % fit - without assigning colors manually
                plot(app.df_Axes, app.ROIsData.linFit_dfF0(:,currROI),'SeriesIndex',rem(i,8))
                text(app.df_Axes, app.stackInfo.nFrames+2, app.ROIsData.traces(end,currROI), ...
                    num2str(currROI))
            end
            
            xlim(app.df_Axes, [0,app.stackInfo.nFrames + 10])
            % The df by F0 trace makes most sense, when centered around 0,
            % which we achieve here by having the same min and max ylim
            ylim(app.df_Axes,'padded'); 
            ylims = ylim(app.df_Axes);
            newYlim = max(abs(ylims));
            ylim(app.df_Axes, [-newYlim, newYlim])
            xlabel(app.df_Axes,sprintf('Frames, at fps of %.2f Hz', app.stackInfo.fps))
            ylabel(app.df_Axes,'Z-score')

            if app.ShowlegendCB.Value
                legend(app.df_Axes, string(app.activeROIs))
            else
                % Otherwise an empty/arbitrary legend remains
                legend(app.df_Axes,'off')                    
            end
                
        end


        function readROIsTA(app)
            % The text area stores all numbers as cell-strings with ','
            % inbetween the ROI-values, so we need to split them at every ','. 
            % Also as it is a cell and every row is a new cell containing text. (therefore {r}) 
            % str2double gives an array with the numbers -> convenient
            % Preallcation does not work as we can´t read the cell-contents directly
            nRows = length(app.PlottedROIsTextArea.Value);
            app.activeROIs = [];
            for r = 1:nRows
                app.activeROIs = [app.activeROIs, ...
                    str2double(strsplit(app.PlottedROIsTextArea.Value{r},{',',' '}))];
            end
            % After assigning values we discard values that occured more
            % than once (bad user input)
            app.activeROIs = unique(app.activeROIs);
            % Then we get rid of the "0" and of potential NaN aswell
            app.activeROIs = app.activeROIs(app.activeROIs > 0);
            % Now we make sure no number exeeds the number of available ROIsw
            app.activeROIs = app.activeROIs(app.activeROIs <= size(app.ROIsData.traces,2));
            
            % Now the Text Area is updated so the user sees the correct input
            app.PlottedROIsTextArea.Value = num2str(app.activeROIs);
        end
        
        
        function linFit(app)
            %column vec of [1 1]; [1 2], [1 3] ...[1 nFrames]
            X = [ones(app.stackInfo.nFrames,1) (1:app.stackInfo.nFrames)'];
            fitVal = X\app.ROIsData.traces;
            app.ROIsData.linFit = X*fitVal;
            app.ROIsData.linFit_dfF0 = ...
                (app.ROIsData.traces-app.ROIsData.linFit)./app.ROIsData.linFit;
        end
        
        function expFit(app)
            app.ROIsData.expFit = [];
            pgb = uiprogressdlg(app.UIFigure, ...
                        'Title', 'Calculating exponential fits ');
            
            x =(1:app.stackInfo.nFrames)';
            
            for i = 1:app.ROIsData.nROIs
                pgb.Value = i/app.ROIsData.nROIs;
                
                y = app.ROIsData.traces(:,i);
                
                % We need a offset "b(1)" as the curves don´t start and
                % won´t reach 0 (physiologically)
                % We need a high "b(2)", because our exponent "b(3)-b(4)*x"
                % will be very small - Small change in flourescence over
                % many frames
                % We need the negative b(3) to adjust the speed of decay
                % We need a very small negative b(4) to actually model the process
                % correctly (many frames, slow decay)
                modelfun = @(b,x) b(1) + b(2) * exp(b(3)-b(4)*x(:,1));
                
                % These values seemd fine when first testing and may need
                % to be either adjusted later on, or even better
                % automatically adjusted to the specific curve before
                % fitting, or entered by the user - so many options
                coef0 = [100, 250, -0.5, -0.005];
            
                mdl = fitnlm(x, y, modelfun, coef0);
            
                coef = mdl.Coefficients{:, 'Estimate'};
                app.ROIsData.expFit.coef(:,i) = coef;
                app.ROIsData.expFit.yFitted(:,i) = coef(1) + coef(2) * exp(coef(3)-coef(4)*x);
            end
            
            try
                delete(pgb);
            catch
                %apparently the progressbar was already closed by the user?
            end
        end
        
        
       function updateROIs(app)
            try
                app.mainApp.updateROIs(app.ROIsData)
            catch
                errordlg("Could not load ROIs-Fits and df/F0 data into the main window")
            end
       end
        
        function testplot(app)
            curROI = app.activeROIs(1);
            newTrace = min(app.ROIsData.linFit(:,curROI),app.ROIsData.traces(:,curROI));
            x =(1:app.stackInfo.nFrames)';
            y = newTrace;
            
            modelfun = @(b,x) b(1) + b(2) * exp(b(3)-b(4)*x(:,1));
            
            coef0 = [100, 250, -0.5, -0.005];
        
            mdl = fitnlm(x, y, modelfun, coef0);
        
            coef = mdl.Coefficients{:, 'Estimate'};
            %app.ROIsData.expFit.coef(:,curROI) = coef;
            %app.ROIsData.expFit.yFitted(:,curROI) = coef(1) + coef(2) * exp(coef(3)-coef(4)*x);
            newFit = coef(1) + coef(2) * exp(coef(3)-coef(4)*x);
            
            figure('Name','OldTrace + linFit + oldExpFit(magenta)')
            plot(app.ROIsData.traces(:,curROI),'blue')
            hold on
            plot(app.ROIsData.linFit(:,curROI),'blue')
            plot(app.ROIsData.expFit.yFitted(:,curROI),'magenta')
            
            figure('Name','newTrace + newFit(yellow)')
            plot(newTrace,'red')
            hold on
            plot(newFit,'yellow')
            
            figure('Name','oldTrace + oldExpFit(magenta) + newFit(yellow)')
            plot(app.ROIsData.traces(:,curROI),'blue')
            hold on
            plot(app.ROIsData.expFit.yFitted(:,curROI),'magenta')
            plot(newFit,'yellow')
            
            figure('Name','Original df_F0')
            plot(app.ROIsData.linFit_dfF0(:,curROI))
            
            figure('Name','New df_F0')
            new_df_F0 = (app.ROIsData.traces(:,curROI)-newFit)./newFit;
            plot(new_df_F0)
            
            figure('Name','smooth New df_F0')
            sdF = smooth(new_df_F0,'sgolay');
            plot(sdF)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            % So no user interaction while loading (relevant when fitting)
            switchInteractivity(app,0)
            app.newCalc = 0;
            
            app.mainApp = mainApp;
            app.stack = mainApp.stack;
            app.stackInfo = mainApp.stackInfo;
            app.fileName = mainApp.fileName;
            app.dataPath = mainApp.dataPath;
            app.ROIsData = mainApp.ROIsData;
            
            disableDefaultInteractivity(app.TracesAxes)
            app.TracesAxes.Toolbar.Visible = false;
            
            app.PlottedROIsTextArea.Value = num2str(1:5);
            readROIsTA(app)
            
            if ~isfield(app.ROIsData,'linFit')
                linFit(app)
                app.newCalc = 1;
            end
            
            %test
            expFit(app)
            if ~isfield(app.ROIsData,'expFit')
                if ~isemtpy(app.ROIsData.expFit)
                    expFit(app)
                end
                app.newCalc = 1;
            end
            
            plotTraces(app)
            
            switchInteractivity(app,1)
        end

        % Value changed function: PlottedROIsTextArea
        function PlottedROIsTextAreaValueChanged(app, event)
            readROIsTA(app)
            plotTraces(app)
        end

        % Value changed function: ShowlegendCB
        function ShowlegendCBValueChanged(app, event)
            plotTraces(app)
        end

        % Button pushed function: ShowallButton
        function ShowallButtonPushed(app, event)
            % This functions first fills the table and then reads from it
            % to make sure everything fits together
            app.PlottedROIsTextArea.Value = num2str(1:size(app.ROIsData.traces,2));
            readROIsTA(app)
            plotTraces(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                if app.newCalc == 1
                    pgbMA = uiprogressdlg(app.mainApp.UIFigure, ...
                        'Title', 'Closing CalciumTraces',...
                        'Indeterminate','on');
                    
                    pgb = uiprogressdlg(app.UIFigure, ...
                        'Title', 'Closing CalciumTraces',...
                        'Indeterminate','on');
                    SaveROIs(app.dataPath, app.fileName, app.ROIsData)
                    updateROIs(app)
                    try
                        delete(pgb)
                        delete(pgbMA)
                    catch
                        error('Progress bar was closed before the files could be updated.')
                    end
                end
            catch e
                disp(e)
            end
            delete(app)
        end

        % Button pushed function: NewnonlinearfitButton
        function NewnonlinearfitButtonPushed(app, event)
            expFit(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TracesAxes = uiaxes(app.UIFigure);
            title(app.TracesAxes, 'Title')
            xlabel(app.TracesAxes, 'X')
            ylabel(app.TracesAxes, 'Y')
            zlabel(app.TracesAxes, 'Z')
            app.df_Axes = uiaxes(app.UIFigure);
            title(app.df_Axes, 'Title')
            xlabel(app.df_Axes, 'X')
            ylabel(app.df_Axes, 'Y')
            zlabel(app.df_Axes, 'Z')
            app.PlottedROIsTextAreaLabel = uilabel(app.UIFigure);
            app.PlottedROIsTextAreaLabel.Text = {'Plotted '; 'ROIs'};
            app.PlottedROIsTextArea = uitextarea(app.UIFigure);
            app.PlottedROIsTextArea.ValueChangedFcn = createCallbackFcn(app, @PlottedROIsTextAreaValueChanged, true);
            app.ShowlegendCB = uicheckbox(app.UIFigure);
            app.ShowlegendCB.ValueChangedFcn = createCallbackFcn(app, @ShowlegendCBValueChanged, true);
            app.ShowlegendCB.Text = 'Show legend';
            app.ShowallButton = uibutton(app.UIFigure, 'push');
            app.ShowallButton.ButtonPushedFcn = createCallbackFcn(app, @ShowallButtonPushed, true);
            app.ShowallButton.Text = 'Show all';
            app.NewnonlinearfitButton = uibutton(app.UIFigure, 'push');
            app.NewnonlinearfitButton.ButtonPushedFcn = createCallbackFcn(app, @NewnonlinearfitButtonPushed, true);
            app.NewnonlinearfitButton.Text = 'New non-linear fit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CalciumTraces(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end